classdef UserInterfaceProto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ManualInputTab                 matlab.ui.container.Tab
        ManualInputPanel               matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        ConsoleTextArea                matlab.ui.control.TextArea
        ConsoleLabel                   matlab.ui.control.Label
        WinLossPanel                   matlab.ui.container.Panel
        WinLossAxes                    matlab.ui.control.UIAxes
        TradingLogTable                matlab.ui.control.Table
        PanelResults                   matlab.ui.container.Panel
        ResultsLabel                   matlab.ui.control.Label
        LoseCountEditField             matlab.ui.control.EditField
        LoseCountEditFieldLabel        matlab.ui.control.Label
        MaxDailyLossEditField          matlab.ui.control.EditField
        MaxDailyLossEditFieldLabel     matlab.ui.control.Label
        MaxDailyWinEditField           matlab.ui.control.EditField
        MaxDailyWinEditFieldLabel      matlab.ui.control.Label
        WinRateEditField               matlab.ui.control.EditField
        WinRateEditFieldLabel          matlab.ui.control.Label
        WinCountEditField              matlab.ui.control.EditField
        WinCountEditFieldLabel         matlab.ui.control.Label
        TotalTradesEditField           matlab.ui.control.EditField
        TotalTradesEditFieldLabel      matlab.ui.control.Label
        TotalProfitEditField           matlab.ui.control.EditField
        TotalProfitEditField_2Label    matlab.ui.control.Label
        PanelConfig                    matlab.ui.container.Panel
        EndDateEditField               matlab.ui.control.EditField
        EndDateEditFieldLabel          matlab.ui.control.Label
        StartDateEditField             matlab.ui.control.EditField
        StartDateLabel                 matlab.ui.control.Label
        EntryTimeEditField             matlab.ui.control.EditField
        EntryTimeLabel                 matlab.ui.control.Label
        EntryCreditEditField           matlab.ui.control.NumericEditField
        EntryCreditEditField_2Label    matlab.ui.control.Label
        LoadingImage                   matlab.ui.control.Image
        StopLossMultiplierEditField    matlab.ui.control.NumericEditField
        StopLossMultiplierEditFieldLabel  matlab.ui.control.Label
        LimitPriceEditField            matlab.ui.control.NumericEditField
        LimitPriceEditField_2Label     matlab.ui.control.Label
        StopPriceEditField             matlab.ui.control.NumericEditField
        StopPriceEditField_2Label      matlab.ui.control.Label
        NumberofSpreadsEditField       matlab.ui.control.NumericEditField
        NumberofSpreadsEditField_2Label  matlab.ui.control.Label
        SpreadWidthEditField           matlab.ui.control.NumericEditField
        SpreadWidthEditField_2Label    matlab.ui.control.Label
        ConfigurationLabel             matlab.ui.control.Label
        ExportResultsButton            matlab.ui.control.Button
        RunTestButton                  matlab.ui.control.Button
        ProfitPanel                    matlab.ui.container.Panel
        ProfitAxes                     matlab.ui.control.UIAxes
        AdditionalMetricsTab           matlab.ui.container.Tab
        AdditionalMetricsPanel         matlab.ui.container.Panel
        DailyDataTable                 matlab.ui.control.Table
        PanelMoreResults               matlab.ui.container.Panel
        AvgLossEditField               matlab.ui.control.EditField
        AverageLossEditFieldLabel      matlab.ui.control.Label
        AvgWinEditField                matlab.ui.control.EditField
        AverageWinEditFieldLabel       matlab.ui.control.Label
        ConsecLoseDaysEditField        matlab.ui.control.EditField
        ConsecutiveLosingDaysEditFieldLabel  matlab.ui.control.Label
        ConsecWinDaysEditField         matlab.ui.control.EditField
        ConsecutiveWinningDaysEditFieldLabel  matlab.ui.control.Label
        NumWinDaysEditField            matlab.ui.control.EditField
        NumberofWinningDaysEditFieldLabel  matlab.ui.control.Label
        AvgLoseDayEditField            matlab.ui.control.EditField
        AverageLosingDaysLabel         matlab.ui.control.Label
        NumLoseDaysEditField           matlab.ui.control.EditField
        NumberofLosingDaysEditFieldLabel  matlab.ui.control.Label
        MoreResultsLabel               matlab.ui.control.Label
        AvgWinDayEditField             matlab.ui.control.EditField
        TotalProfitEditField_2Label_2  matlab.ui.control.Label
        PieGraphPanel                  matlab.ui.container.Panel
        PutAxes                        matlab.ui.control.UIAxes
        CallAxes                       matlab.ui.control.UIAxes
        HelperFunctionsTab             matlab.ui.container.Tab
        HelperFunctionPanel            matlab.ui.container.Panel
        PanelRF                        matlab.ui.container.Panel
        RFResultsTextArea              matlab.ui.control.TextArea
        ResultsTextArea_3Label_2       matlab.ui.control.Label
        RFDateEditField                matlab.ui.control.NumericEditField
        DateEditField_4Label_2         matlab.ui.control.Label
        RFLoadingImage                 matlab.ui.control.Image
        RFStrikePriceEditField         matlab.ui.control.NumericEditField
        TimestampEditField_2Label_2    matlab.ui.control.Label
        ReadFileLabel                  matlab.ui.control.Label
        RFRunTestButton                matlab.ui.control.Button
        RFDropDown                     matlab.ui.control.DropDown
        PanelSLO                       matlab.ui.container.Panel
        SLOLimitPriceEditField         matlab.ui.control.NumericEditField
        LimitPriceEditField_3Label     matlab.ui.control.Label
        SLOResultsTextArea             matlab.ui.control.TextArea
        ResultsTextArea_3Label         matlab.ui.control.Label
        SLODateEditField               matlab.ui.control.NumericEditField
        DateEditField_4Label           matlab.ui.control.Label
        SLOLoadingImage                matlab.ui.control.Image
        SLOStopPriceEditField          matlab.ui.control.NumericEditField
        StopPriceEditField_3Label      matlab.ui.control.Label
        SLOUpperStrikeEditField        matlab.ui.control.NumericEditField
        UpperStrikeEditField_2Label    matlab.ui.control.Label
        SLOLowerStrikeEditField        matlab.ui.control.NumericEditField
        LowerStrikeEditField_2Label    matlab.ui.control.Label
        SLOTimestampEditField          matlab.ui.control.NumericEditField
        TimestampEditField_2Label      matlab.ui.control.Label
        StopLimitOrderLabel            matlab.ui.control.Label
        SLORunTestButton               matlab.ui.control.Button
        SLODropDown                    matlab.ui.control.DropDown
        PanelSL                        matlab.ui.container.Panel
        SLStopMultiplierEditField      matlab.ui.control.NumericEditField
        StopMultiplierEditFieldLabel   matlab.ui.control.Label
        SLResultsTextArea              matlab.ui.control.TextArea
        ResultsTextArea_2Label         matlab.ui.control.Label
        SLDateEditField                matlab.ui.control.NumericEditField
        DateEditField_3Label           matlab.ui.control.Label
        SLLoadingImage                 matlab.ui.control.Image
        SLEntryCreditEditField         matlab.ui.control.NumericEditField
        EntryCreditEditField_2Label_2  matlab.ui.control.Label
        SLUpperStrikeEditField         matlab.ui.control.NumericEditField
        UpperStrikeEditFieldLabel      matlab.ui.control.Label
        SLLowerStrikeEditField         matlab.ui.control.NumericEditField
        LowerStrikeEditFieldLabel      matlab.ui.control.Label
        SLTimestampEditField           matlab.ui.control.NumericEditField
        TimestampEditFieldLabel        matlab.ui.control.Label
        StopLossLabel                  matlab.ui.control.Label
        SLRunTestButton                matlab.ui.control.Button
        SLDropDown                     matlab.ui.control.DropDown
        PanelFS                        matlab.ui.container.Panel
        FSResultsTextArea              matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        FSDateEditField                matlab.ui.control.NumericEditField
        DateEditField_2Label           matlab.ui.control.Label
        FSLoadingImage                 matlab.ui.control.Image
        FSTimestampofEntryEditField    matlab.ui.control.NumericEditField
        TimestampofEntryEditField_2Label  matlab.ui.control.Label
        FSEntryCreditEditField         matlab.ui.control.NumericEditField
        LimitPriceEditField_2Label_2   matlab.ui.control.Label
        FSSpreadWidthEditField         matlab.ui.control.NumericEditField
        StopPriceEditField_2Label_2    matlab.ui.control.Label
        FSNumberofSpreadsEditField     matlab.ui.control.NumericEditField
        NumberofSpreadsEditField_2Label_2  matlab.ui.control.Label
        FindSpreadsLabel               matlab.ui.control.Label
        FSRunTestButton                matlab.ui.control.Button
        FSDropDown                     matlab.ui.control.DropDown
        VisualizeFileDataTab           matlab.ui.container.Tab
        PlotPanel                      matlab.ui.container.Panel
        PlotDateEditField              matlab.ui.control.NumericEditField
        DateEditField_4Label_3         matlab.ui.control.Label
        PlotLoadingImage               matlab.ui.control.Image
        PlotStrikePriceEditField       matlab.ui.control.NumericEditField
        TimestampEditField_2Label_3    matlab.ui.control.Label
        PlotFileLabel                  matlab.ui.control.Label
        PlotRunTestButton              matlab.ui.control.Button
        PlotDropDown                   matlab.ui.control.DropDown
        PlotAxes                       matlab.ui.control.UIAxes
        BacktesterLabel                matlab.ui.control.Label
    end

    methods (Access = private)
        
        function displayProfitOverTime(app)
            % display profit over time graph
            if isfile('user-interface/PnL.txt')
                % clear graph
                cla(app.ProfitAxes, 'reset');

                green = [15, 157, 88] / 255;
                red = [219, 68, 55] / 255;
                grey = [240, 240, 240] / 255;
        
                % read data
                data = readmatrix('user-interface/PnL.txt');
                dates = datetime(num2str(data(:, 1)), 'InputFormat', 'yyyyMMdd');
                prices = data(:, 2);
                
                % interpolated data
                new_data = [];
                indices = [];
                new_prices = [];
                for i = 1:length(prices)-1
                    new_data = [new_data; prices(i)];
                    indices = [indices; dates(i)];
                    new_prices = [new_prices; prices(i)];
                    
                    % check if sign changes between two data points
                    if sign(prices(i)) ~= sign(prices(i+1))
                        % add point with y=0 between the two data points
                        new_data = [new_data; 0];
                        indices = [indices; dates(i) + (dates(i+1) - dates(i))/2];
                        new_prices = [new_prices; NaN]; % add NaN to new_prices
                    end
                end
                new_data = [new_data; prices(end)];
                indices = [indices; dates(end)];
                new_prices = [new_prices; prices(end)];
        
                % separate data with negative profit
                data_below_zero = new_data;
                data_below_zero(new_data > 0) = NaN;
        
                % plot data
                hold(app.ProfitAxes, 'on'); 
                area(app.ProfitAxes, indices, new_data, 'FaceColor', grey, 'EdgeColor', green, 'LineWidth', 1.5, 'HitTest', 'off');
                area(app.ProfitAxes, indices, data_below_zero, 'FaceColor', grey, 'EdgeColor', red, 'LineWidth', 1.5, 'HitTest', 'off');
                scatter(app.ProfitAxes, indices, new_prices, 'filled', 'MarkerFaceColor', 'k', 'HitTest', 'on', 'SizeData', 0.1);
                hold(app.ProfitAxes, 'off');

                title(app.ProfitAxes, 'Profit Over Time', 'FontSize', 10);
                xlabel(app.ProfitAxes, 'Date', 'FontSize', 10);
                ylabel(app.ProfitAxes, 'Profit', 'FontSize', 10);
                set(app.ProfitAxes, 'FontSize', 10);
        
                drawnow;
                fid = fopen('user-interface/PnL.txt', 'w');
                fclose(fid);
            end
        end
        
        function displayTradingLog(app)
            if isfile('user-interface/TradingLog.txt')
                % update table
                app.TradingLogTable.Data = {};
                T = readtable('user-interface/TradingLog.txt', 'Delimiter', ' ');
                app.TradingLogTable.Data = T;
                
                % apply style
                s1 = uistyle('HorizontalAlignment','right');
                addStyle(app.TradingLogTable,s1);
                
                drawnow;
                fid = fopen('user-interface/TradingLog.txt', 'w');
                fclose(fid);
            end
        end
        
        function displayWinLossGraph(app)
            green = [15, 157, 88] / 255;
            red = [219, 68, 55] / 255;
        
            if isfile('user-interface/TotalDailyWinLoss.txt')
                cla(app.WinLossAxes, 'reset');
                T = readtable('user-interface/TotalDailyWinLoss.txt', 'Delimiter', ' ');
        
                data = table2array(T);
                dates = datetime(string(data(:, 1)), 'InputFormat', 'yyyyMMdd');
        
                wins = data(:, 2);
                losses = -data(:, 3);
                tt = timetable(dates, wins, losses, 'VariableNames', {'Wins', 'Losses'});
        
                % change to weekly or monthly data based on the number of data points
                numPoints = height(tt);
                if height(tt) > 400
                    tt = retime(tt, 'monthly', 'sum');
                elseif height(tt) > 70
                    tt = retime(tt, 'weekly', 'sum');
                end
        
                % Plot the wins and losses
                b = bar(app.WinLossAxes, tt.dates, [tt.Wins, tt.Losses], 'stacked');
                b(1).FaceColor = green;
                b(1).EdgeColor = 'none';
                b(2).FaceColor = red;
                b(2).EdgeColor = 'none';
        
                if numPoints > 400
                    title(app.WinLossAxes, 'Monthly Total Wins and Losses', 'FontSize', 10);
                elseif numPoints > 70
                    title(app.WinLossAxes, 'Weekly Total Wins and Losses', 'FontSize', 10);
                else
                    title(app.WinLossAxes, 'Daily Total Wins and Losses', 'FontSize', 10);
                end

                xlabel(app.WinLossAxes, 'Date', 'FontSize', 10);
                ylabel(app.WinLossAxes, 'Profit', 'FontSize', 10);
                set(app.WinLossAxes, 'FontSize', 10);
        
                drawnow;
                fid = fopen('user-interface/TotalDailyWinLoss.txt', 'w');
                fclose(fid);
            end
        end
        
        function displayPieGraphs(app, call_data, call_win_count, call_lose_count, put_data, put_win_count, put_lose_count)
            green = [15, 157, 88] / 255;
            red = [219, 68, 55] / 255;

            callPie = pie(app.CallAxes, call_data);
            colormap(app.CallAxes, [green; red]);
            title(app.CallAxes, 'Call Spreads: Wins and Losses')
            percentages = call_data / sum(call_data);
            labels = {sprintf('Loss: %d (%.1f%%)', call_lose_count, percentages(2)*100), ...
                      sprintf('Win: %d (%.1f%%)', call_win_count, percentages(1)*100)};
            hText = findobj(app.CallAxes, 'Type', 'text');
            set(hText, {'String'}, labels');
            set(findobj(callPie, 'Type', 'patch'), 'EdgeColor', 'white');

            putPie = pie(app.PutAxes, put_data);
            colormap(app.PutAxes, [green; red]);
            title(app.PutAxes, 'Put Spreads: Wins and Losses')
            percentages = put_data / sum(put_data);
            labels = {sprintf('Loss: %d (%.1f%%)', put_lose_count, percentages(2)*100), ...
                      sprintf('Win: %d (%.1f%%)', put_win_count, percentages(1)*100)};
            hText = findobj(app.PutAxes, 'Type', 'text');
            set(hText, {'String'}, labels');
            set(findobj(putPie, 'Type', 'patch'), 'EdgeColor', 'white');
        end
        
        function displayDailyData(app)
            if isfile('user-interface/DailyData.txt')
                % update table
                app.DailyDataTable.Data = {};
                T = readtable('user-interface/DailyData.txt', 'Delimiter', ' ');
                app.DailyDataTable.Data = T;
                
                % apply style
                s1 = uistyle('HorizontalAlignment','right');
                addStyle(app.DailyDataTable,s1);
                
                drawnow;
                fid = fopen('user-interface/DailyData.txt', 'w');
                fclose(fid);
            end
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunTestButton
        function runBacktest(app, event)
            clearText(app, event);
            app.RunTestButton.Enable = 'off';
            app.ExportResultsButton.Enable = 'off';
            app.LoadingImage.Visible = 'on';
            app.ConsoleTextArea.Value = "";
            app.ConsoleTextArea.FontColor = 'red';
            error = false;

            % check if time is between 9:30 and 15:59
            entryTime = app.EntryTimeEditField.Value;
            isValid = ~isempty(regexp(entryTime,'^(0?[9]:[3-5]\d?|1[0-5]:[0-5]\d)$','once'));
            if (~isValid)
                % display error message
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Entry time must be between 9:30 and 15:59.'}], '\n');
                error = true;
            else
                % covert to int
                entryTime = strrep(entryTime, ':', '');
                entryTime = [entryTime, '00000'];
                entryTime = str2double(entryTime);
            end

            % check if start date is between 2020-01-01 to 2024-03-1
            startDate = app.StartDateEditField.Value;
            isValid = ~isempty(regexp(startDate,'^\d{4}-\d{2}-\d{2}$','once'));
            if (~isValid)
                % display error message
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Start date must be in the format of yyyy-mm-dd.'}], '\n');
                error = true;
            else
                try
                    % convert to datetime
                    startDate = datetime(startDate, 'InputFormat', 'yyyy-MM-dd');                
                    tmp_startDate = datetime('2020-01-01', 'InputFormat', 'yyyy-MM-dd');
                    tmp_endDate = datetime('2024-03-01', 'InputFormat', 'yyyy-MM-dd');
                    
                    % check if date is valid
                    isValidDate = (startDate >= tmp_startDate) && (startDate <= tmp_endDate);
                    if (~isValidDate)
                        app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Start date must be between 2020-01-01 and 2024-03-01.'}], '\n');
                        error = true;
                    else
                        % convert to int
                        startDate = datestr(startDate,'yyyymmdd');
                        startDate = str2double(startDate);
                    end
                catch
                    % display error message
                    app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Invalid start date. Please enter a valid date in the format yyyy-mm-dd.'}], '\n');
                    error = true;
                end
            end

            % check if end date is between 2020-01-01 to 2024-03-1
            endDate = app.EndDateEditField.Value;
            isValid = ~isempty(regexp(endDate,'^\d{4}-\d{2}-\d{2}$','once'));
            if (~isValid)
                % display error message
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'End date must be in the format of yyyy-mm-dd.'}], '\n');
                error = true;
            else
                try
                    % convert to datetime
                    endDate = datetime(endDate, 'InputFormat', 'yyyy-MM-dd');                
                    tmp_startDate = datetime('2020-01-01', 'InputFormat', 'yyyy-MM-dd');
                    tmp_endDate = datetime('2024-03-01', 'InputFormat', 'yyyy-MM-dd');
                    
                    % check if date is valid
                    isValidDate = (endDate >= tmp_startDate) && (endDate <= tmp_endDate);
                    if (~isValidDate)
                        app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'End date must be between 2020-01-01 and 2024-03-01.'}], '\n');
                        error = true;
                    else
                        % convert to int
                        endDate = datestr(endDate,'yyyymmdd');
                        endDate = str2double(endDate);
                    end
                catch
                    % display error message
                    app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Invalid end date. Please enter a valid date in the format yyyy-mm-dd.'}], '\n');
                    error = true;
                end
            end

            % colours
            green = [15, 157, 88] / 255;
            red = [219, 68, 55] / 255;

            % get input
            spreadWidth = app.SpreadWidthEditField.Value;
            entryCredit = app.EntryCreditEditField.Value;
            numSpreads = app.NumberofSpreadsEditField.Value;
            stopPrice = app.StopPriceEditField.Value;
            limitPrice = app.LimitPriceEditField.Value;
            stopLoss = app.StopLossMultiplierEditField.Value;

            % validating input
            if (entryCredit == 0 || stopPrice == 0 || limitPrice == 0)
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Entry credit, stop price, and limit price must have non-zero values.'}], '\n');
                error = true;
            end
            if (entryCredit < stopPrice)
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Entry credit must be greater than stop price.'}], '\n');
                error = true;
            end
            if (stopPrice < limitPrice)
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Stop price must be greater than limit price.'}], '\n');
                error = true;
            end
            if (limitPrice > entryCredit)
                app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Limit price must be less than entry credit.'}], '\n');
                error = true;
            end

            % do not run backtest
            if (error == true)
                app.RunTestButton.Enable = 'on';
                app.ExportResultsButton.Enable = 'on';
                app.LoadingImage.Visible = "off";
                return;
            end
            
            app.ConsoleTextArea.FontColor = 'black';
            app.ConsoleTextArea.Value = 'Running Backtest...';
            drawnow;

            mod = py.importlib.import_module('meic_ui_variant');
            result = mod.process_spreads_multithreaded(startDate, endDate, entryTime, spreadWidth, entryCredit, numSpreads, stopPrice, limitPrice, stopLoss);
            array = cell(result);

            % update text fields
            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Updating Results...'}], '\n');
            drawnow;
            app.TotalProfitEditField.Value = num2str(array{1});
            profit = str2double(app.TotalProfitEditField.Value);
            if profit < 0
                app.TotalProfitEditField.FontColor = red;
            else
                app.TotalProfitEditField.FontColor = green;
            end

            app.TotalTradesEditField.Value = num2str(double(array{2}) + double(array{3}));
            app.WinCountEditField.Value = num2str(double(array{2}));
            app.LoseCountEditField.Value = num2str(double(array{3}));
            app.WinRateEditField.Value = num2str(array{4});
            app.MaxDailyWinEditField.Value = num2str(array{5});
            app.MaxDailyLossEditField.Value = num2str(array{6});
            call_win_count = array{7};
            call_lose_count = array{8};
            call_data = [call_win_count, call_lose_count];
            put_win_count = array{9};
            put_lose_count = array{10};
            put_data = [put_win_count, put_lose_count];
            app.NumWinDaysEditField.Value = num2str(array{11});
            app.NumLoseDaysEditField.Value = num2str(array{12});
            app.AvgWinDayEditField.Value = num2str(array{13});
            app.AvgLoseDayEditField.Value = num2str(array{14});
            app.ConsecWinDaysEditField.Value = num2str(array{15});
            app.ConsecLoseDaysEditField.Value = num2str(array{16});
            app.AvgWinEditField.Value = num2str(array{17});
            app.AvgLossEditField.Value = num2str(array{18});

            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Populating Trading Log...'}], '\n');
            drawnow;
            displayTradingLog(app);

            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Displaying Daily Wins and Losses Graph...'}], '\n');
            drawnow;
            displayWinLossGraph(app);

            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Displaying Profit Over Time Graph...'}], '\n');
            drawnow;
            displayProfitOverTime(app);

            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Plotting Pie Graphs for Call/Put Wins and Losses...'}], '\n');
            drawnow;
            displayPieGraphs(app, call_data, call_win_count, call_lose_count, put_data, put_win_count, put_lose_count);

            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Populating Daily Data Table...'}], '\n');
            drawnow;
            displayDailyData(app);

            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {'Complete!'}], '\n');
            app.RunTestButton.Enable = 'on';
            app.ExportResultsButton.Enable = 'on';
            app.LoadingImage.Visible = "off";
        end

        % Callback function
        function clearText(app, event)
            % clear all values
            app.TradingLogTable.Data = {};
            cla(app.ProfitAxes, 'reset');
            cla(app.WinLossAxes, 'reset');
            app.TotalProfitEditField.Value = "";
            app.TotalTradesEditField.Value = "";
            app.WinCountEditField.Value = "";
            app.LoseCountEditField.Value = "";
            app.WinRateEditField.Value = "";
            app.MaxDailyWinEditField.Value = "";
            app.MaxDailyLossEditField.Value = "";
            app.ConsoleTextArea.Value = "";

            app.DailyDataTable.Data = {};
            app.AvgLossEditField.Value = "";
            app.AvgWinEditField.Value = "";
            app.ConsecLoseDaysEditField.Value = "";
            app.ConsecWinDaysEditField.Value = "";
            app.NumWinDaysEditField.Value = "";
            app.AvgLoseDayEditField.Value = "";
            app.NumLoseDaysEditField.Value = "";
            app.AvgWinDayEditField.Value = "";
            cla(app.PutAxes, 'reset');
            cla(app.CallAxes, 'reset');
        end

        % Button pushed function: FSRunTestButton
        function runFindSpreads(app, event)
            app.FSLoadingImage.Visible = "on";
            app.FSRunTestButton.Enable = "off";
            app.FSResultsTextArea.Value = "";
            drawnow;

            % clear file
            if isfile('user-interface/FindSpreads.txt')
                fid = fopen('user-interface/FindSpreads.txt', 'w');
                fclose(fid);
            end

            % get values
            date = app.FSDateEditField.Value;
            timestamp = app.FSTimestampofEntryEditField.Value;
            numSpreads = app.FSNumberofSpreadsEditField.Value
            entryCredit = app.FSEntryCreditEditField.Value;
            spreadWidth = app.FSSpreadWidthEditField.Value;
            
            if strcmp(app.FSDropDown.Value, 'Call')
                isCall = 1;
            elseif strcmp(app.FSDropDown.Value, 'Put')
                isCall = 0;
            end

            mod = py.importlib.import_module('meic_ui_variant');
            result = mod.find_strikes_ui(date, timestamp, entryCredit, spreadWidth, numSpreads, isCall);

            if isfile('user-interface/FindSpreads.txt')
                data = fileread('user-interface/FindSpreads.txt');
                app.FSResultsTextArea.Value = data;

                drawnow;
                fid = fopen('user-interface/FindSpreads.txt', 'w');
                fclose(fid);
            end

            app.FSRunTestButton.Enable = "on";
            app.FSLoadingImage.Visible = "off";
        end

        % Button pushed function: SLRunTestButton
        function runStopLoss(app, event)
            app.SLLoadingImage.Visible = "on";
            app.SLRunTestButton.Enable = "off";
            app.SLResultsTextArea.Value = "";
            drawnow;

            % clear file
            if isfile('user-interface/StopLoss.txt')
                fid = fopen('user-interface/StopLoss.txt', 'w');
                fclose(fid);
            end

            % get values
            date = app.SLDateEditField.Value;
            timestamp = app.SLTimestampEditField.Value;
            lower = app.SLLowerStrikeEditField.Value;
            upper = app.SLUpperStrikeEditField.Value;
            entryCredit = app.SLEntryCreditEditField.Value;
            stopMultiplier = app.SLStopMultiplierEditField.Value;

            if strcmp(app.FSDropDown.Value, 'Call')
                isCall = 1;
            elseif strcmp(app.FSDropDown.Value, 'Put')
                isCall = 0;
            end

            mod = py.importlib.import_module('meic_ui_variant');
            result = mod.stop_loss_ui(date, lower, upper, timestamp, entryCredit, stopMultiplier, isCall);

            if isfile('user-interface/StopLoss.txt')
                data = fileread('user-interface/StopLoss.txt');
                app.SLResultsTextArea.Value = data;

                drawnow;
                fid = fopen('user-interface/StopLoss.txt', 'w');
                fclose(fid);
            end

            app.SLRunTestButton.Enable = "on";
            app.SLLoadingImage.Visible = "off";
        end

        % Button pushed function: SLORunTestButton
        function runStopLimitOrder(app, event)
            app.SLOLoadingImage.Visible = "on";
            app.SLORunTestButton.Enable = "off";
            app.SLOResultsTextArea.Value = "";
            drawnow;

            % clear fle
            if isfile('user-interface/StopLimitOrder.txt')
                fid = fopen('user-interface/StopLimitOrder.txt', 'w');
                fclose(fid);
            end

            % get values
            date = app.SLODateEditField.Value;
            timestamp = app.SLOTimestampEditField.Value;
            lower = app.SLOLowerStrikeEditField.Value;
            upper = app.SLOUpperStrikeEditField.Value;
            stop = app.SLOStopPriceEditField.Value;
            limit = app.SLOLimitPriceEditField.Value;

            if strcmp(app.SLODropDown.Value, 'Call')
                isCall = 1;
            elseif strcmp(app.SLODropDown.Value, 'Put')
                isCall = 0;
            end

            mod = py.importlib.import_module('meic_ui_variant');
            result = mod.stop_limit_order_ui(date, lower, upper, timestamp, stop, limit, isCall);

            if isfile('user-interface/StopLimitOrder.txt')
                data = fileread('user-interface/StopLimitOrder.txt');
                app.SLOResultsTextArea.Value = data;

                drawnow;
                fid = fopen('user-interface/StopLimitOrder.txt', 'w');
                fclose(fid);
            end

            app.SLORunTestButton.Enable = "on";
            app.SLOLoadingImage.Visible = "off";
        end

        % Button pushed function: RFRunTestButton
        function runReadFile(app, event)
            app.RFLoadingImage.Visible = "on";
            app.RFRunTestButton.Enable = "off";
            app.RFResultsTextArea.Value = "";
            drawnow;

            % clear file
            if isfile('user-interface/PrintFile.txt')
                fid = fopen('user-interface/PrintFile.txt', 'w');
                fclose(fid);
            end 

            % get values
            date = app.RFDateEditField.Value;
            strike = app.RFStrikePriceEditField.Value;

            if strcmp(app.SLODropDown.Value, 'Call')
                isCall = 1;
            elseif strcmp(app.SLODropDown.Value, 'Put')
                isCall = 0;
            end

            mod = py.importlib.import_module('meic_ui_variant');
            result = mod.print_file_ui(date, strike, isCall);

            if isfile('user-interface/PrintFile.txt')
                data = fileread('user-interface/PrintFile.txt');
                app.RFResultsTextArea.Value = data;

                drawnow;
                fid = fopen('user-interface/PrintFile.txt', 'w');
                fclose(fid);
            end

            app.RFRunTestButton.Enable = "on";
            app.RFLoadingImage.Visible = "off";
        end

        % Button pushed function: ExportResultsButton
        function exportResults(app, event)
            app.ExportResultsButton.Enable = 'off';

            % create folder to store backtest results
            currentDateTime = datetime('now');
            dateString = datestr(currentDateTime, 'yyyy-mm-dd');
            timeString = datestr(currentDateTime, 'HH-MM-SS');
            folderName = ['DATE_', dateString, '_TIME_', timeString];
            folderName = "saved-backtest-results/" + folderName;
            mkdir(folderName);
            
            % export trading log
            data = app.TradingLogTable.Data;
            if istable(data)
                data = table2cell(data);
            end
            headers = {'Date', 'Type', 'Spread', 'Credit at Open', 'Execution Time', 'Execution Credit', 'Stop Out Time', 'Stop Out Price', 'Profit'};
            T = cell2table(data, 'VariableNames', headers);
            filename1 = folderName + '/TradingLog.xlsx';
            writetable(T, filename1);

            % export daily data
            data = app.DailyDataTable.Data;
            if istable(data)
                data = table2cell(data);
            end
            headers = {'Date', 'Profit', 'C (Wins/Losses)', 'C (Profit/Losses)', 'P (Wins/Losses)', 'P (Profit/Losses)', 'Win Rate', 'Winnings', 'Losses'};
            T = cell2table(data, 'VariableNames', headers);
            filename2 = folderName + '/DailyData.xlsx';
            writetable(T, filename2);

            % file to store results
            filename3 = folderName + '/results.txt';
            fileID = fopen(filename3, 'w');

            % export inputs
            x = app.EntryTimeEditField.Value;
            fprintf(fileID, 'Entry Time %s\n', x);
            x = app.StartDateEditField.Value;
            fprintf(fileID, 'Start Date %s\n', x);
            x = app.EndDateEditField.Value;
            fprintf(fileID, 'End Date %s\n', x);
            x = app.SpreadWidthEditField.Value;
            fprintf(fileID, 'Spread Width %f\n', x);
            x = app.EntryCreditEditField.Value;
            fprintf(fileID, 'Entry Credit %f\n', x);
            x = app.NumberofSpreadsEditField.Value;
            fprintf(fileID, 'Number of Spreads %f\n', x);
            x = app.StopPriceEditField.Value;
            fprintf(fileID, 'Stop Price %f\n', x);
            x = app.LimitPriceEditField.Value;
            fprintf(fileID, 'Limit Price %f\n', x);
            x = app.StopLossMultiplierEditField.Value;
            fprintf(fileID, 'Stop Loss Multiplier %f\n', x);

            x = app.TotalProfitEditField.Value;
            fprintf(fileID, 'Total Profit %s\n', x);
            x = app.TotalTradesEditField.Value;
            fprintf(fileID, 'Total Trades %s\n', x);
            x = app.WinCountEditField.Value;
            fprintf(fileID, 'Win Count %s\n', x);
            x = app.LoseCountEditField.Value;
            fprintf(fileID, 'Lose Count %s\n', x);
            x = app.WinRateEditField.Value;
            fprintf(fileID, 'Win Rate %s\n', x);
            x = app.MaxDailyWinEditField.Value;
            fprintf(fileID, 'Max Daily Win %s\n', x);
            x = app.MaxDailyLossEditField.Value;
            fprintf(fileID, 'Max Daily Loss %s\n', x);
            
            x = app.AvgLossEditField.Value;
            fprintf(fileID, 'Average Loss %s\n', x);
            x = app.AvgWinEditField.Value;
            fprintf(fileID, 'Average Win %s\n', x);
            x = app.ConsecLoseDaysEditField.Value;
            fprintf(fileID, 'Consecutive Lose Days %s\n', x);
            x = app.ConsecWinDaysEditField.Value;
            fprintf(fileID, 'Consecutive Win Days %s\n', x);
            x = app.NumWinDaysEditField.Value;
            fprintf(fileID, 'Number of Win Days %s\n', x);
            x = app.AvgLoseDayEditField.Value;
            fprintf(fileID, 'Average Lose Day %s\n', x);
            x = app.NumLoseDaysEditField.Value;
            fprintf(fileID, 'Number of Lose Days %s\n', x);
            x = app.AvgWinDayEditField.Value;
            fprintf(fileID, 'Average Win Day %s\n', x);
            fclose(fileID);

            output = ['Exported results to ', char(folderName)];
            app.ConsoleTextArea.Value = strjoin([app.ConsoleTextArea.Value; {output}], '\n');
            drawnow;
        end

        % Value changed function: EntryCreditEditField
        function updateStopLimitOrder(app, event)
            % update stop and limit price based on entry credit
            value = app.EntryCreditEditField.Value;
            
            % must be a multiple of 0.1
            stopPrice = round(value * 0.75 * 10) / 10;
            app.StopPriceEditField.Value = stopPrice;
            
            limitPrice = round(value * 0.65 * 10) / 10;
            app.LimitPriceEditField.Value = limitPrice;
        end

        % Value changed function: StopPriceEditField
        function updateLimitPrice(app, event)
            value = app.StopPriceEditField.Value;
            
            % must be a multiple of 0.1
            limitPrice = round(value * 0.95 * 10) / 10;

            % limitPrice must be aleast 0.1 less than stopPrice
            if limitPrice >= value - 0.1
                limitPrice = value - 0.1;
            end

            % limit price must be positive
            if limitPrice <= 0
                limitPrice = 0.1;
            end
            app.LimitPriceEditField.Value = limitPrice;
        end

        % Button pushed function: PlotRunTestButton
        function runPlotData(app, event)
            app.PlotLoadingImage.Visible = "on";
            app.PlotRunTestButton.Enable = "off";
            cla(app.PlotAxes, 'reset');
            drawnow;

            % clear file
            if isfile('user-interface/PlotFile.txt')
                fid = fopen('user-interface/PlotFile.txt', 'w');
                fclose(fid);
            end 

            % get values
            date = app.PlotDateEditField.Value;
            strike = app.PlotStrikePriceEditField.Value;

            if strcmp(app.PlotDropDown.Value, 'Call')
                isCall = 1;
            elseif strcmp(app.PlotDropDown.Value, 'Put')
                isCall = 0;
            end

            mod = py.importlib.import_module('meic_ui_variant');
            result = mod.plot_file_ui(date, strike, isCall);

            if isfile('user-interface/PlotFile.txt')
                fileInfo = dir('user-interface/PlotFile.txt');
                if fileInfo.bytes > 0
                    data = readtable('user-interface/PlotFile.txt', 'Format', '%{HH:mm:ss:SSSSSS}D %f');
                else
                    title(app.PlotAxes, "File Does Not Exist");
                    app.PlotLoadingImage.Visible = "off";
                    app.PlotRunTestButton.Enable = "on";
                    return
                end
                
                if ~isempty(data)
                    green = [15, 157, 88] / 255;
                    plot(app.PlotAxes, data.Var1, data.Var2, 'Color', green);
                    title(app.PlotAxes, 'Mid Price Over Time');
                    xlabel(app.PlotAxes, 'Time');
                    ylabel(app.PlotAxes, 'Mid Price');
                    drawnow;
                end

                drawnow;
                fid = fopen('user-interface/PlotFile.txt', 'w');
                fclose(fid);
            end

            app.PlotLoadingImage.Visible = "off";
            app.PlotRunTestButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BacktesterLabel = uilabel(app.UIFigure);
            app.BacktesterLabel.Text = '   Backtester';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualInputTab = uitab(app.TabGroup);
            app.ManualInputTab.Title = 'Manual Input';
            app.ManualInputPanel = uipanel(app.ManualInputTab);
            app.ProfitPanel = uipanel(app.ManualInputPanel);
            app.ProfitAxes = uiaxes(app.ProfitPanel);
            title(app.ProfitAxes, 'Profit Over Time')
            xlabel(app.ProfitAxes, 'Date')
            ylabel(app.ProfitAxes, 'Profit')
            zlabel(app.ProfitAxes, 'Z')
            app.PanelConfig = uipanel(app.ManualInputPanel);
            app.RunTestButton = uibutton(app.PanelConfig, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @runBacktest, true);
            app.RunTestButton.Text = 'Run Test';
            app.ExportResultsButton = uibutton(app.PanelConfig, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @exportResults, true);
            app.ExportResultsButton.Enable = 'off';
            app.ExportResultsButton.Text = 'Export Results';
            app.ConfigurationLabel = uilabel(app.PanelConfig);
            app.ConfigurationLabel.Text = 'Configuration';
            app.SpreadWidthEditField_2Label = uilabel(app.PanelConfig);
            app.SpreadWidthEditField_2Label.Text = 'Spread Width';
            app.SpreadWidthEditField = uieditfield(app.PanelConfig, 'numeric');
            app.SpreadWidthEditField.Limits = [1 Inf];
            app.SpreadWidthEditField.Value = 30;
            app.NumberofSpreadsEditField_2Label = uilabel(app.PanelConfig);
            app.NumberofSpreadsEditField_2Label.Text = 'Number of Spreads';
            app.NumberofSpreadsEditField = uieditfield(app.PanelConfig, 'numeric');
            app.NumberofSpreadsEditField.Limits = [1 Inf];
            app.NumberofSpreadsEditField.Value = 3;
            app.StopPriceEditField_2Label = uilabel(app.PanelConfig);
            app.StopPriceEditField_2Label.Text = 'Stop Price';
            app.StopPriceEditField = uieditfield(app.PanelConfig, 'numeric');
            app.StopPriceEditField.Limits = [0 Inf];
            app.StopPriceEditField.ValueChangedFcn = createCallbackFcn(app, @updateLimitPrice, true);
            app.StopPriceEditField.Value = 1.1;
            app.LimitPriceEditField_2Label = uilabel(app.PanelConfig);
            app.LimitPriceEditField_2Label.Text = 'Limit Price';
            app.LimitPriceEditField = uieditfield(app.PanelConfig, 'numeric');
            app.LimitPriceEditField.Limits = [0 Inf];
            app.LimitPriceEditField.Value = 1;
            app.StopLossMultiplierEditFieldLabel = uilabel(app.PanelConfig);
            app.StopLossMultiplierEditFieldLabel.Text = 'Stop Loss Multiplier';
            app.StopLossMultiplierEditField = uieditfield(app.PanelConfig, 'numeric');
            app.StopLossMultiplierEditField.Limits = [1 Inf];
            app.StopLossMultiplierEditField.Value = 2;
            app.LoadingImage = uiimage(app.PanelConfig);
            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = 'loading.gif';
            app.EntryCreditEditField_2Label = uilabel(app.PanelConfig);
            app.EntryCreditEditField_2Label.Text = 'Entry Credit';
            app.EntryCreditEditField = uieditfield(app.PanelConfig, 'numeric');
            app.EntryCreditEditField.Limits = [0 Inf];
            app.EntryCreditEditField.ValueChangedFcn = createCallbackFcn(app, @updateStopLimitOrder, true);
            app.EntryCreditEditField.Value = 1.5;
            app.EntryTimeLabel = uilabel(app.PanelConfig);
            app.EntryTimeLabel.Text = 'Entry Time';
            app.EntryTimeEditField = uieditfield(app.PanelConfig, 'text');
            app.EntryTimeEditField.CharacterLimits = [0 5];
            app.EntryTimeEditField.Value = '10:00';
            app.StartDateLabel = uilabel(app.PanelConfig);
            app.StartDateLabel.Text = 'Start Date';
            app.StartDateEditField = uieditfield(app.PanelConfig, 'text');
            app.StartDateEditField.Value = '2023-01-01';
            app.EndDateEditFieldLabel = uilabel(app.PanelConfig);
            app.EndDateEditFieldLabel.Text = 'End Date';
            app.EndDateEditField = uieditfield(app.PanelConfig, 'text');
            app.EndDateEditField.Value = '2023-02-01';
            app.PanelResults = uipanel(app.ManualInputPanel);
            app.TotalProfitEditField_2Label = uilabel(app.PanelResults);
            app.TotalProfitEditField_2Label.Text = 'Total Profit';
            app.TotalProfitEditField = uieditfield(app.PanelResults, 'text');
            app.TotalProfitEditField.Editable = 'off';
            app.TotalTradesEditFieldLabel = uilabel(app.PanelResults);
            app.TotalTradesEditFieldLabel.Text = 'Total Trades';
            app.TotalTradesEditField = uieditfield(app.PanelResults, 'text');
            app.TotalTradesEditField.Editable = 'off';
            app.WinCountEditFieldLabel = uilabel(app.PanelResults);
            app.WinCountEditFieldLabel.Text = 'Win Count';
            app.WinCountEditField = uieditfield(app.PanelResults, 'text');
            app.WinCountEditField.Editable = 'off';
            app.WinRateEditFieldLabel = uilabel(app.PanelResults);
            app.WinRateEditFieldLabel.Text = 'Win Rate';
            app.WinRateEditField = uieditfield(app.PanelResults, 'text');
            app.WinRateEditField.Editable = 'off';
            app.MaxDailyWinEditFieldLabel = uilabel(app.PanelResults);
            app.MaxDailyWinEditFieldLabel.Text = 'Max Daily Win';
            app.MaxDailyWinEditField = uieditfield(app.PanelResults, 'text');
            app.MaxDailyWinEditField.Editable = 'off';
            app.MaxDailyLossEditFieldLabel = uilabel(app.PanelResults);
            app.MaxDailyLossEditFieldLabel.Text = 'Max Daily Loss';
            app.MaxDailyLossEditField = uieditfield(app.PanelResults, 'text');
            app.MaxDailyLossEditField.Editable = 'off';
            app.LoseCountEditFieldLabel = uilabel(app.PanelResults);
            app.LoseCountEditFieldLabel.Text = 'Lose Count';
            app.LoseCountEditField = uieditfield(app.PanelResults, 'text');
            app.LoseCountEditField.Editable = 'off';
            app.ResultsLabel = uilabel(app.PanelResults);
            app.ResultsLabel.Text = 'Results';
            app.TradingLogTable = uitable(app.ManualInputPanel);
            app.TradingLogTable.ColumnName = {'Date'; 'Type'; 'Spread'; 'Credit at Open'; 'Execution Time'; 'Execution Credit'; 'Stop Out Time'; 'Stop Out Price'; 'Profit'};
            app.TradingLogTable.RowName = {};
            app.WinLossPanel = uipanel(app.ManualInputPanel);
            app.WinLossAxes = uiaxes(app.WinLossPanel);
            title(app.WinLossAxes, 'Total Daily Wins and Losses')
            xlabel(app.WinLossAxes, 'Date')
            ylabel(app.WinLossAxes, 'Profit')
            zlabel(app.WinLossAxes, 'Z')
            app.Panel = uipanel(app.ManualInputPanel);
            app.ConsoleLabel = uilabel(app.Panel);
            app.ConsoleLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.Panel);
            app.AdditionalMetricsTab = uitab(app.TabGroup);
            app.AdditionalMetricsTab.Title = 'Additional Metrics';
            app.AdditionalMetricsPanel = uipanel(app.AdditionalMetricsTab);
            app.PieGraphPanel = uipanel(app.AdditionalMetricsPanel);
            app.CallAxes = uiaxes(app.PieGraphPanel);
            title(app.CallAxes, 'Call Spreads: Wins and Losses')
            xlabel(app.CallAxes, 'X')
            ylabel(app.CallAxes, 'Y')
            zlabel(app.CallAxes, 'Z')
            app.PutAxes = uiaxes(app.PieGraphPanel);
            title(app.PutAxes, 'Put Spreads: Wins and Losses')
            xlabel(app.PutAxes, 'X')
            ylabel(app.PutAxes, 'Y')
            zlabel(app.PutAxes, 'Z')
            app.PanelMoreResults = uipanel(app.AdditionalMetricsPanel);
            app.TotalProfitEditField_2Label_2 = uilabel(app.PanelMoreResults);
            app.TotalProfitEditField_2Label_2.Text = 'Average Winning Day';
            app.AvgWinDayEditField = uieditfield(app.PanelMoreResults, 'text');
            app.AvgWinDayEditField.Editable = 'off';
            app.MoreResultsLabel = uilabel(app.PanelMoreResults);
            app.MoreResultsLabel.Text = 'More Results';
            app.NumberofLosingDaysEditFieldLabel = uilabel(app.PanelMoreResults);
            app.NumberofLosingDaysEditFieldLabel.Text = 'Number of Losing Days';
            app.NumLoseDaysEditField = uieditfield(app.PanelMoreResults, 'text');
            app.NumLoseDaysEditField.Editable = 'off';
            app.AverageLosingDaysLabel = uilabel(app.PanelMoreResults);
            app.AverageLosingDaysLabel.Text = 'Average Losing Days';
            app.AvgLoseDayEditField = uieditfield(app.PanelMoreResults, 'text');
            app.AvgLoseDayEditField.Editable = 'off';
            app.NumberofWinningDaysEditFieldLabel = uilabel(app.PanelMoreResults);
            app.NumberofWinningDaysEditFieldLabel.Text = 'Number of Winning Days';
            app.NumWinDaysEditField = uieditfield(app.PanelMoreResults, 'text');
            app.NumWinDaysEditField.Editable = 'off';
            app.ConsecutiveWinningDaysEditFieldLabel = uilabel(app.PanelMoreResults);
            app.ConsecutiveWinningDaysEditFieldLabel.Text = 'Consecutive Winning Days';
            app.ConsecWinDaysEditField = uieditfield(app.PanelMoreResults, 'text');
            app.ConsecWinDaysEditField.Editable = 'off';
            app.ConsecutiveLosingDaysEditFieldLabel = uilabel(app.PanelMoreResults);
            app.ConsecutiveLosingDaysEditFieldLabel.Text = 'Consecutive Losing Days';
            app.ConsecLoseDaysEditField = uieditfield(app.PanelMoreResults, 'text');
            app.ConsecLoseDaysEditField.Editable = 'off';
            app.AverageWinEditFieldLabel = uilabel(app.PanelMoreResults);
            app.AverageWinEditFieldLabel.Text = 'Average Win';
            app.AvgWinEditField = uieditfield(app.PanelMoreResults, 'text');
            app.AvgWinEditField.Editable = 'off';
            app.AverageLossEditFieldLabel = uilabel(app.PanelMoreResults);
            app.AverageLossEditFieldLabel.Text = 'Average Loss';
            app.AvgLossEditField = uieditfield(app.PanelMoreResults, 'text');
            app.AvgLossEditField.Editable = 'off';
            app.DailyDataTable = uitable(app.AdditionalMetricsPanel);
            app.DailyDataTable.ColumnName = {'Date'; 'Profit'; 'C (Wins/Losses)'; 'C (Profit/Losses)'; 'P (Wins/Losses)'; 'P (Profit/Losses)'; 'Win Rate'; 'Winnings'; 'Losses'};
            app.DailyDataTable.RowName = {};
            app.HelperFunctionsTab = uitab(app.TabGroup);
            app.HelperFunctionsTab.Title = 'Helper Functions';
            app.HelperFunctionPanel = uipanel(app.HelperFunctionsTab);
            app.PanelFS = uipanel(app.HelperFunctionPanel);
            app.FSDropDown = uidropdown(app.PanelFS);
            app.FSDropDown.Items = {'Call', 'Put'};
            app.FSDropDown.Value = 'Call';
            app.FSRunTestButton = uibutton(app.PanelFS, 'push');
            app.FSRunTestButton.ButtonPushedFcn = createCallbackFcn(app, @runFindSpreads, true);
            app.FSRunTestButton.Text = 'Run Test';
            app.FindSpreadsLabel = uilabel(app.PanelFS);
            app.FindSpreadsLabel.Text = 'Find Spreads';
            app.NumberofSpreadsEditField_2Label_2 = uilabel(app.PanelFS);
            app.NumberofSpreadsEditField_2Label_2.Text = 'Number of Spreads';
            app.FSNumberofSpreadsEditField = uieditfield(app.PanelFS, 'numeric');
            app.FSNumberofSpreadsEditField.Value = 3;
            app.StopPriceEditField_2Label_2 = uilabel(app.PanelFS);
            app.StopPriceEditField_2Label_2.Text = 'Spread Width';
            app.FSSpreadWidthEditField = uieditfield(app.PanelFS, 'numeric');
            app.FSSpreadWidthEditField.Value = 30;
            app.LimitPriceEditField_2Label_2 = uilabel(app.PanelFS);
            app.LimitPriceEditField_2Label_2.Text = 'Entry Credit';
            app.FSEntryCreditEditField = uieditfield(app.PanelFS, 'numeric');
            app.FSEntryCreditEditField.Value = 1.5;
            app.TimestampofEntryEditField_2Label = uilabel(app.PanelFS);
            app.TimestampofEntryEditField_2Label.Text = 'Timestamp of Entry';
            app.FSTimestampofEntryEditField = uieditfield(app.PanelFS, 'numeric');
            app.FSTimestampofEntryEditField.ValueDisplayFormat = '%.0f';
            app.FSTimestampofEntryEditField.Value = 100000000;
            app.FSLoadingImage = uiimage(app.PanelFS);
            app.FSLoadingImage.Visible = 'off';
            app.FSLoadingImage.ImageSource = 'loading.gif';
            app.DateEditField_2Label = uilabel(app.PanelFS);
            app.DateEditField_2Label.Text = 'Date';
            app.FSDateEditField = uieditfield(app.PanelFS, 'numeric');
            app.FSDateEditField.ValueDisplayFormat = '%.0f';
            app.FSDateEditField.Value = 20230103;
            app.ResultsTextAreaLabel = uilabel(app.PanelFS);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.FSResultsTextArea = uitextarea(app.PanelFS);
            app.FSResultsTextArea.Editable = 'off';
            app.PanelSL = uipanel(app.HelperFunctionPanel);
            app.SLDropDown = uidropdown(app.PanelSL);
            app.SLDropDown.Items = {'Call', 'Put'};
            app.SLDropDown.Value = 'Call';
            app.SLRunTestButton = uibutton(app.PanelSL, 'push');
            app.SLRunTestButton.ButtonPushedFcn = createCallbackFcn(app, @runStopLoss, true);
            app.SLRunTestButton.Text = 'Run Test';
            app.StopLossLabel = uilabel(app.PanelSL);
            app.StopLossLabel.Text = 'Stop Loss';
            app.TimestampEditFieldLabel = uilabel(app.PanelSL);
            app.TimestampEditFieldLabel.Text = 'Timestamp';
            app.SLTimestampEditField = uieditfield(app.PanelSL, 'numeric');
            app.SLTimestampEditField.ValueDisplayFormat = '%.0f';
            app.SLTimestampEditField.Value = 100000000;
            app.LowerStrikeEditFieldLabel = uilabel(app.PanelSL);
            app.LowerStrikeEditFieldLabel.Text = 'Lower Strike';
            app.SLLowerStrikeEditField = uieditfield(app.PanelSL, 'numeric');
            app.SLLowerStrikeEditField.Value = 3875;
            app.UpperStrikeEditFieldLabel = uilabel(app.PanelSL);
            app.UpperStrikeEditFieldLabel.Text = 'Upper Strike';
            app.SLUpperStrikeEditField = uieditfield(app.PanelSL, 'numeric');
            app.SLUpperStrikeEditField.Value = 3905;
            app.EntryCreditEditField_2Label_2 = uilabel(app.PanelSL);
            app.EntryCreditEditField_2Label_2.Text = 'Entry Credit';
            app.SLEntryCreditEditField = uieditfield(app.PanelSL, 'numeric');
            app.SLEntryCreditEditField.Value = 1;
            app.SLLoadingImage = uiimage(app.PanelSL);
            app.SLLoadingImage.Visible = 'off';
            app.SLLoadingImage.ImageSource = 'loading.gif';
            app.DateEditField_3Label = uilabel(app.PanelSL);
            app.DateEditField_3Label.Text = 'Date';
            app.SLDateEditField = uieditfield(app.PanelSL, 'numeric');
            app.SLDateEditField.ValueDisplayFormat = '%.0f';
            app.SLDateEditField.Value = 20230103;
            app.ResultsTextArea_2Label = uilabel(app.PanelSL);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.SLResultsTextArea = uitextarea(app.PanelSL);
            app.SLResultsTextArea.Editable = 'off';
            app.StopMultiplierEditFieldLabel = uilabel(app.PanelSL);
            app.StopMultiplierEditFieldLabel.Text = 'Stop Multiplier';
            app.SLStopMultiplierEditField = uieditfield(app.PanelSL, 'numeric');
            app.SLStopMultiplierEditField.Value = 2;
            app.PanelSLO = uipanel(app.HelperFunctionPanel);
            app.SLODropDown = uidropdown(app.PanelSLO);
            app.SLODropDown.Items = {'Call', 'Put'};
            app.SLODropDown.Value = 'Call';
            app.SLORunTestButton = uibutton(app.PanelSLO, 'push');
            app.SLORunTestButton.ButtonPushedFcn = createCallbackFcn(app, @runStopLimitOrder, true);
            app.SLORunTestButton.Text = 'Run Test';
            app.StopLimitOrderLabel = uilabel(app.PanelSLO);
            app.StopLimitOrderLabel.Text = 'Stop Limit Order';
            app.TimestampEditField_2Label = uilabel(app.PanelSLO);
            app.TimestampEditField_2Label.Text = 'Timestamp';
            app.SLOTimestampEditField = uieditfield(app.PanelSLO, 'numeric');
            app.SLOTimestampEditField.ValueDisplayFormat = '%.0f';
            app.SLOTimestampEditField.Value = 100000000;
            app.LowerStrikeEditField_2Label = uilabel(app.PanelSLO);
            app.LowerStrikeEditField_2Label.Text = 'Lower Strike';
            app.SLOLowerStrikeEditField = uieditfield(app.PanelSLO, 'numeric');
            app.SLOLowerStrikeEditField.Value = 3875;
            app.UpperStrikeEditField_2Label = uilabel(app.PanelSLO);
            app.UpperStrikeEditField_2Label.Text = 'Upper Strike';
            app.SLOUpperStrikeEditField = uieditfield(app.PanelSLO, 'numeric');
            app.SLOUpperStrikeEditField.Value = 3905;
            app.StopPriceEditField_3Label = uilabel(app.PanelSLO);
            app.StopPriceEditField_3Label.Text = 'Stop Price';
            app.SLOStopPriceEditField = uieditfield(app.PanelSLO, 'numeric');
            app.SLOStopPriceEditField.Value = 1.1;
            app.SLOLoadingImage = uiimage(app.PanelSLO);
            app.SLOLoadingImage.Visible = 'off';
            app.SLOLoadingImage.ImageSource = 'loading.gif';
            app.DateEditField_4Label = uilabel(app.PanelSLO);
            app.DateEditField_4Label.Text = 'Date';
            app.SLODateEditField = uieditfield(app.PanelSLO, 'numeric');
            app.SLODateEditField.ValueDisplayFormat = '%.0f';
            app.SLODateEditField.Value = 20230103;
            app.ResultsTextArea_3Label = uilabel(app.PanelSLO);
            app.ResultsTextArea_3Label.Text = 'Results';
            app.SLOResultsTextArea = uitextarea(app.PanelSLO);
            app.SLOResultsTextArea.Editable = 'off';
            app.LimitPriceEditField_3Label = uilabel(app.PanelSLO);
            app.LimitPriceEditField_3Label.Text = 'Limit Price';
            app.SLOLimitPriceEditField = uieditfield(app.PanelSLO, 'numeric');
            app.SLOLimitPriceEditField.Value = 1;
            app.PanelRF = uipanel(app.HelperFunctionPanel);
            app.RFDropDown = uidropdown(app.PanelRF);
            app.RFDropDown.Items = {'Call', 'Put'};
            app.RFDropDown.Value = 'Call';
            app.RFRunTestButton = uibutton(app.PanelRF, 'push');
            app.RFRunTestButton.ButtonPushedFcn = createCallbackFcn(app, @runReadFile, true);
            app.RFRunTestButton.Text = 'Run Test';
            app.ReadFileLabel = uilabel(app.PanelRF);
            app.ReadFileLabel.Text = 'Read File';
            app.TimestampEditField_2Label_2 = uilabel(app.PanelRF);
            app.TimestampEditField_2Label_2.Text = 'Strike Price';
            app.RFStrikePriceEditField = uieditfield(app.PanelRF, 'numeric');
            app.RFStrikePriceEditField.ValueDisplayFormat = '%.0f';
            app.RFStrikePriceEditField.Value = 3875;
            app.RFLoadingImage = uiimage(app.PanelRF);
            app.RFLoadingImage.Visible = 'off';
            app.RFLoadingImage.ImageSource = 'loading.gif';
            app.DateEditField_4Label_2 = uilabel(app.PanelRF);
            app.DateEditField_4Label_2.Text = 'Date';
            app.RFDateEditField = uieditfield(app.PanelRF, 'numeric');
            app.RFDateEditField.ValueDisplayFormat = '%.0f';
            app.RFDateEditField.Value = 20230103;
            app.ResultsTextArea_3Label_2 = uilabel(app.PanelRF);
            app.ResultsTextArea_3Label_2.Text = 'Results';
            app.RFResultsTextArea = uitextarea(app.PanelRF);
            app.RFResultsTextArea.Editable = 'off';
            app.VisualizeFileDataTab = uitab(app.TabGroup);
            app.VisualizeFileDataTab.Title = 'Visualize File Data';
            app.PlotPanel = uipanel(app.VisualizeFileDataTab);
            app.PlotAxes = uiaxes(app.PlotPanel);
            title(app.PlotAxes, 'Mid Price Over Time')
            xlabel(app.PlotAxes, 'Time')
            ylabel(app.PlotAxes, 'Mid Price')
            zlabel(app.PlotAxes, 'Z')
            app.PlotDropDown = uidropdown(app.PlotPanel);
            app.PlotDropDown.Items = {'Call', 'Put'};
            app.PlotDropDown.Value = 'Put';
            app.PlotRunTestButton = uibutton(app.PlotPanel, 'push');
            app.PlotRunTestButton.ButtonPushedFcn = createCallbackFcn(app, @runPlotData, true);
            app.PlotRunTestButton.Text = 'Run Test';
            app.PlotFileLabel = uilabel(app.PlotPanel);
            app.PlotFileLabel.Text = 'Plot File';
            app.TimestampEditField_2Label_3 = uilabel(app.PlotPanel);
            app.TimestampEditField_2Label_3.Text = 'Strike Price';
            app.PlotStrikePriceEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PlotStrikePriceEditField.ValueDisplayFormat = '%.0f';
            app.PlotStrikePriceEditField.Value = 4000;
            app.PlotLoadingImage = uiimage(app.PlotPanel);
            app.PlotLoadingImage.Visible = 'off';
            app.PlotLoadingImage.ImageSource = 'loading.gif';
            app.DateEditField_4Label_3 = uilabel(app.PlotPanel);
            app.DateEditField_4Label_3.Text = 'Date';
            app.PlotDateEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PlotDateEditField.ValueDisplayFormat = '%.0f';
            app.PlotDateEditField.Value = 20230103;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterfaceProto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end